classdef adaptiveFilterBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TapsEditField             matlab.ui.control.NumericEditField
        TapsEditFieldLabel        matlab.ui.control.Label
        UpdateEntireSignalButton  matlab.ui.control.Button
        LearnRateEditField        matlab.ui.control.NumericEditField
        LearnRateEditFieldLabel   matlab.ui.control.Label
        UpdateTimeButton          matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        Slider                    matlab.ui.control.RangeSlider
        SliderLabel               matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        UpdateFreqButton          matlab.ui.control.Button
        Slider2                   matlab.ui.control.RangeSlider
        Slider2Label              matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        sig; % signal object
        demosig; % new signal object for display 
        filtObj; % filter object 
        nUpdates = 10;
    end
    
    methods (Access = private)
        
        function UpdateSignal(app)
            mysig = app.sig;
            myFilt = app.filtObj; 
            numUpdates = app.nUpdates;

            mysig = doHPFilt(myFilt, mysig);
            mysig = getTrainTestWrapper(mysig);
            [mysig, myFilt] = preTrainWtsWrapper(myFilt, mysig, .1*numUpdates);
            [mysig, w_end] = LMSonlineWrapper(myFilt, mysig, numUpdates);
            mysig = doLPFilt(myFilt, mysig);

            mydemosig = extractTimes(mysig, mysig.Preview_Time_Bounds);
            app.demosig = extractChannel(mydemosig, mydemosig.Preview_Channel_Index);

            app.sig = mysig; 
            app.filtObj = myFilt; 

            UpdateDisp(app);
        end

        function UpdateDisp(app)
            name = {'Ground Truth', ...
                    'Noisy', ...
                    'All Filter', ...
                    'LMS', ...
                    'HPF', ...
                    'BPF', ...
                    };
            vnam = {'Data_Ground_Truth', ...
                    'Data_Unfiltered', ...
                    'Data_LMS_LPF', ...
                    'Data_LMS', ...
                    'Data_HPF', ...
                    'Data_BPF', ...
                    };
            lclr = {'k', ... ground truth 
                    'r', ... noisy 
                    'b', ... filt
                    'c', ... lms
                    'm', ... hpf 
                    'g', ... bpf
                    };
            lspc = {'-', ... ground truth
                    '-', ... noisy
                    '-', ... filt
                    '-', ... lms
                    ':', ... hpf
                    ':', ... bpf
                    };
            lwdh = [1.5, ... ground truth
                    1.3, ... noisy
                    1  , ... filt
                    0.5, ... lms
                    0.5, ... hpf
                    0.5, ... bpf
                    ];
            dispSig = app.demosig; 

            axes(app.UIAxes3); % time axes (left) 
            t = dispSig.Times;
            tbnd = [min(t), max(t)];
            lgdIdx = true(size(name));
            for v = 1:length(name)
                y = eval(['dispSig.',vnam{v}]);
                if numel(y)
                    hold on; plot(t,y, lspc{v}, 'LineWidth',lwdh(v), 'LineColor',lclr{v});
                else
                    lgdIdx(v) = false;
                end
                clear y;
            end
            grid on;
            lgd = name(lgdIdx); legend(lgd); 

            axes(app.UIAxes2); % freq axes (right)
            fs = dispSig.SampleRate;
            wbnd = [0,1];
            for v = 1:length(name)
                if lgdIdx(v)
                    y = eval(['dispSig.',vnam{v}]);
                    [w,P] = PowerSpectrum(y,fs);
                    wbnd(1) = min(wbnd(1), min(w));
                    wbnd(2) = max(wbnd(2), max(w));
                    hold on; plot(w,P, lspc{v}, 'LineWidth',lwdh(v), 'LineColor',lclr{v});
                end
                clear y w P;
            end
            grid on; 
            legend(lgd);

            UpdateSliderLims(app, tbnd, wbnd);
        end

        function UpdateSliderLims(app, tbnd, wbnd)
            app.Slider.Limits = tbnd; 
            app.Slider2.Limits = wbnd;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            mysig = loadSignalObj; app.sig = mysig;
            mysig = extractTimes(mysig, mysig.Preview_Time_Bounds);
            app.demosig = extractChannel(mysig, mysig.Preview_Channel_Index);
            UpdateDisp(app);
        end

        % Button pushed function: UpdateTimeButton
        function UpdateTimeButtonPushed(app, event)
            tbnd = app.Slider.Value;
            mysig = app.sig; 
            mysig.Preview_Time_Bounds = tbnd; app.sig = mysig;
            mysig = extractTimes(mysig, mysig.Preview_Time_Bounds);
            app.demosig = extractChannel(mysig, mysig.Preview_Channel_Index);
            UpdateDisp(app);
        end

        % Button pushed function: UpdateEntireSignalButton
        function UpdateEntireSignalButtonPushed(app, event)
            UpdateSignal(app);
        end

        % Button pushed function: UpdateFreqButton
        function UpdateFreqButtonPushed(app, event)
            wbnd = app.Slider2.Value;
            Fs = app.sig.SampleRate;
            mysig = app.demosig;
            myFilt = app.filtObj;
            w1 = wbnd(1); w2 = wbnd(2);
            hpFilt = designfilt('highpassiir', ...
                    'StopbandFrequency', w1, ...
                    'PassbandFrequency', 2*w1, ...
                    'PassbandRipple', .5, ...
                    'StopbandAttenuation', 60, ...
                    'SampleRate', Fs, ... 
                    'DesignMethod', 'butter');
            lpFilt = designfilt('lowpassiir', ...
                    'StopbandFrequency', w2, ...
                    'PassbandFrequency', .5*w2, ...
                    'PassbandRipple', .5, ...
                    'StopbandAttenuation', 60, ...
                    'SampleRate', Fs, ... 
                    'DesignMethod', 'cheby2');
            myFilt.LPF = lpFilt; 
            myFilt.HPF = hpFilt;
            mysig = doHPFilt(myFilt, mysig);
            mysig = doLPFilt(myFilt, mysig);
            app.filtObj = myFilt; 
            app.demosig = mysig;
            UpdateDisp(app);
        end

        % Value changed function: LearnRateEditField
        function LearnRateEditFieldValueChanged(app, event)
            value = app.LearnRateEditField.Value;
            myFilt = app.filtObj;
            mysig = app.demosig; 
            numUpdates = app.nUpdates;
            myFilt.Step_Size = value; 
            [mysig, w_end] = LMSonlineWrapper(myFilt, mysig, numUpdates);
            app.demosig = mysig; 
            app.filtObj = myFilt; 
            UpdateDisp(app);
        end

        % Value changed function: TapsEditField
        function TapsEditFieldValueChanged(app, event)
            value = app.TapsEditField.Value;
            myFilt = app.filtObj;
            mysig = app.demosig; 
            numUpdates = app.nUpdates;
            myFilt.Num_Taps = value; 
            [mysig, w_end] = LMSonlineWrapper(myFilt, mysig, numUpdates);
            app.demosig = mysig; 
            app.filtObj = myFilt; 
            UpdateDisp(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.LeftPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel, 'range');
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UpdateTimeButton = uibutton(app.LeftPanel, 'push');
            app.UpdateTimeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTimeButtonPushed, true);
            app.UpdateTimeButton.Text = 'Update Time';
            app.LearnRateEditFieldLabel = uilabel(app.LeftPanel);
            app.LearnRateEditFieldLabel.Text = 'Learn Rate';
            app.LearnRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LearnRateEditField.ValueChangedFcn = createCallbackFcn(app, @LearnRateEditFieldValueChanged, true);
            app.UpdateEntireSignalButton = uibutton(app.LeftPanel, 'push');
            app.UpdateEntireSignalButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEntireSignalButtonPushed, true);
            app.UpdateEntireSignalButton.Text = 'Update Entire Signal';
            app.TapsEditFieldLabel = uilabel(app.LeftPanel);
            app.TapsEditFieldLabel.Text = '# Taps';
            app.TapsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TapsEditField.ValueChangedFcn = createCallbackFcn(app, @TapsEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Slider2Label = uilabel(app.RightPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.RightPanel, 'range');
            app.UpdateFreqButton = uibutton(app.RightPanel, 'push');
            app.UpdateFreqButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFreqButtonPushed, true);
            app.UpdateFreqButton.Text = 'Update Freq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adaptiveFilterBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end